classdef DoAnCuoiKi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        RunButton                       matlab.ui.control.Button
        GIAODIENTINHTICHPHANLabel       matlab.ui.control.Label
        KetquaLabel                     matlab.ui.control.Label
        NhapCanAEditFieldLabel          matlab.ui.control.Label
        NhapCanAEditField               matlab.ui.control.EditField
        NhapCanBEditFieldLabel          matlab.ui.control.Label
        NhapCanBEditField               matlab.ui.control.EditField
        NhapNEditFieldLabel             matlab.ui.control.Label
        NhapNEditField                  matlab.ui.control.EditField
        NhapHamEditFieldLabel           matlab.ui.control.Label
        NhapHamEditField                matlab.ui.control.EditField
        TichphanhinhthangTextAreaLabel  matlab.ui.control.Label
        TichphanhinhthangTextArea       matlab.ui.control.TextArea
        TichphanSimpsonTextAreaLabel    matlab.ui.control.Label
        TichphanSimpsonTextArea         matlab.ui.control.TextArea
        TichphanchinhxacTextAreaLabel   matlab.ui.control.Label
        TichphanchinhxacTextArea        matlab.ui.control.TextArea
    end

    
    methods (Access = private)
        
        function results = trapezoidal(~,fx,a,b,N)
            h = (b - a)/N;
            sum = 0;
            for i = 1:N-1
                sum = sum + 2*fx(a + i*h);
            end
            results = h/2*(fx(a) + fx(b) + sum);
        end
        
        function result = Simpson(~,fx,a,b,N)
            h = (b - a)/N;
            sum = 0;
            for i = 1: N-1
                if mod(i,2)
                    sum = sum + 4*fx(a + i*h);
                else
                    sum = sum + 2*fx(a + i*h);
                end
            end
            result = h/3*(fx(a) + fx(b) + sum);  
        end
        function result = exactIntegral(~,fx, a, b)
            result = integral(fx, a, b);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            syms x;            % Get input values
            fx = str2func(['@(x) ' app.NhapHamEditField.Value]);
            a = str2num(app.NhapCanAEditField.Value);
            b = str2num(app.NhapCanBEditField.Value);
            N = str2num(app.NhapNEditField.Value);
            
            trapezoidal_result = app.trapezoidal(fx, a, b, N);
            simpson_result = app.Simpson(fx, a, b, N);
            exact_result = app.exactIntegral(fx, a, b);
            
            result_str=num2str(trapezoidal_result,'%.4f');
            result_str1=num2str(simpson_result,'%.4f');
            result_str2=num2str(exact_result,'%.4f');
            app.TichphanhinhthangTextArea.Value = result_str;
            app.TichphanSimpsonTextArea.Value = result_str1;
            app.TichphanchinhxacTextArea.Value = result_str2;
            
            x = linspace(a, b, 1000);
            y = arrayfun(fx, x);
            plot(app.UIAxes, x, y);
            title(app.UIAxes, 'Graph of f(x)');
            xlabel(app.UIAxes, 'x');
            ylabel(app.UIAxes, 'f(x)');
            grid (app.UIAxes,"on")
        end

        % Callback function
        function NhapHamEditFieldValueChanged(app, event)
  
        end

        % Callback function
        function NhapHamEditFieldValueChanging(app, event)

            
        end

        % Callback function: NhapCanAEditField, NhapCanAEditField
        function NhapCanAEditFieldValueChanged(app, event)
            value = app.NhapCanAEditField.Value;
            
        end

        % Callback function: NhapCanBEditField, NhapCanBEditField
        function NhapCanBEditFieldValueChanged(app, event)
            value = app.NhapCanBEditField.Value;
            
        end

        % Callback function: NhapNEditField, NhapNEditField
        function NhapNEditFieldValueChanged(app, event)
            value = app.NhapNEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Do thi tich phan')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'f(x)')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = 'run.png';
            app.RunButton.Text = 'Run';
            app.GIAODIENTINHTICHPHANLabel = uilabel(app.UIFigure);
            app.GIAODIENTINHTICHPHANLabel.Text = 'GIAO DIEN TINH TICH PHAN';
            app.KetquaLabel = uilabel(app.UIFigure);
            app.KetquaLabel.Text = 'Ket qua';
            app.NhapCanAEditFieldLabel = uilabel(app.UIFigure);
            app.NhapCanAEditFieldLabel.Text = 'Nhap Can A';
            app.NhapCanAEditField = uieditfield(app.UIFigure, 'text');
            app.NhapCanAEditField.ValueChangedFcn = createCallbackFcn(app, @NhapCanAEditFieldValueChanged, true);
            app.NhapCanAEditField.ValueChangingFcn = createCallbackFcn(app, @NhapCanAEditFieldValueChanged, true);
            app.NhapCanBEditFieldLabel = uilabel(app.UIFigure);
            app.NhapCanBEditFieldLabel.Text = 'Nhap Can B';
            app.NhapCanBEditField = uieditfield(app.UIFigure, 'text');
            app.NhapCanBEditField.ValueChangedFcn = createCallbackFcn(app, @NhapCanBEditFieldValueChanged, true);
            app.NhapCanBEditField.ValueChangingFcn = createCallbackFcn(app, @NhapCanBEditFieldValueChanged, true);
            app.NhapNEditFieldLabel = uilabel(app.UIFigure);
            app.NhapNEditFieldLabel.Text = 'Nhap N';
            app.NhapNEditField = uieditfield(app.UIFigure, 'text');
            app.NhapNEditField.ValueChangedFcn = createCallbackFcn(app, @NhapNEditFieldValueChanged, true);
            app.NhapNEditField.ValueChangingFcn = createCallbackFcn(app, @NhapNEditFieldValueChanged, true);
            app.NhapHamEditFieldLabel = uilabel(app.UIFigure);
            app.NhapHamEditFieldLabel.Text = 'Nhap Ham';
            app.NhapHamEditField = uieditfield(app.UIFigure, 'text');
            app.TichphanhinhthangTextAreaLabel = uilabel(app.UIFigure);
            app.TichphanhinhthangTextAreaLabel.Text = 'Tich phan hinh thang';
            app.TichphanhinhthangTextArea = uitextarea(app.UIFigure);
            app.TichphanSimpsonTextAreaLabel = uilabel(app.UIFigure);
            app.TichphanSimpsonTextAreaLabel.Text = 'Tich phan Simpson';
            app.TichphanSimpsonTextArea = uitextarea(app.UIFigure);
            app.TichphanchinhxacTextAreaLabel = uilabel(app.UIFigure);
            app.TichphanchinhxacTextAreaLabel.Text = 'Tich phanchinh xac';
            app.TichphanchinhxacTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DoAnCuoiKi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end